classdef APP_MY9 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Panel_3               matlab.ui.container.Panel
        StartRunButton_27     matlab.ui.control.Button
        StartRunButton_26     matlab.ui.control.Button
        StartRunButton_25     matlab.ui.control.Button
        StartRunButton_10     matlab.ui.control.Button
        TextArea_2            matlab.ui.control.TextArea
        Label_19              matlab.ui.control.Label
        StartRunButton_11     matlab.ui.control.Button
        Panel_4               matlab.ui.container.Panel
        StartRunButton_13     matlab.ui.control.Button
        StartRunButton_9      matlab.ui.control.Button
        Button_3              matlab.ui.control.StateButton
        Lamp                  matlab.ui.control.Lamp
        DropDown_4            matlab.ui.control.DropDown
        Label_18              matlab.ui.control.Label
        DataBit               matlab.ui.control.DropDown
        Label_17              matlab.ui.control.Label
        BaudRate              matlab.ui.control.DropDown
        Label_16              matlab.ui.control.Label
        COM_port              matlab.ui.control.DropDown
        Label_15              matlab.ui.control.Label
        StartRunButton_2      matlab.ui.control.Button
        TabGroup              matlab.ui.container.TabGroup
        Tab                   matlab.ui.container.Tab
        TabGroup2             matlab.ui.container.TabGroup
        Tab_4                 matlab.ui.container.Tab
        StartRunButton_28     matlab.ui.control.Button
        StartRunButton_12     matlab.ui.control.Button
        StartRunButton_7      matlab.ui.control.Button
        StartRunButton_3      matlab.ui.control.Button
        CSpinner_3            matlab.ui.control.Spinner
        CSpinner_3Label       matlab.ui.control.Label
        BSpinner_3            matlab.ui.control.Spinner
        BSpinner_3Label       matlab.ui.control.Label
        ASpinner_3            matlab.ui.control.Spinner
        ASpinner_3Label       matlab.ui.control.Label
        ZSpinner_3            matlab.ui.control.Spinner
        ZSpinner_3Label       matlab.ui.control.Label
        YSpinner_3            matlab.ui.control.Spinner
        YSpinner_3Label       matlab.ui.control.Label
        XSpinner_3            matlab.ui.control.Spinner
        XSpinner_3Label       matlab.ui.control.Label
        Label_2               matlab.ui.control.Label
        CSpinner_2            matlab.ui.control.Spinner
        CSpinner_2Label       matlab.ui.control.Label
        BSpinner_2            matlab.ui.control.Spinner
        BSpinner_2Label       matlab.ui.control.Label
        ASpinner_2            matlab.ui.control.Spinner
        ASpinner_2Label       matlab.ui.control.Label
        ZSpinner_2            matlab.ui.control.Spinner
        ZSpinner_2Label       matlab.ui.control.Label
        YSpinner_2            matlab.ui.control.Spinner
        YSpinner_2Label       matlab.ui.control.Label
        XSpinner_2            matlab.ui.control.Spinner
        XSpinner_2Label       matlab.ui.control.Label
        Label                 matlab.ui.control.Label
        Tab_5                 matlab.ui.container.Tab
        StartRunButton_30     matlab.ui.control.Button
        StartRunButton_29     matlab.ui.control.Button
        StartRunButton_19     matlab.ui.control.Button
        CSpinner_8            matlab.ui.control.Spinner
        CSpinner_8Label       matlab.ui.control.Label
        ZSpinner_9            matlab.ui.control.Spinner
        ZSpinner_9Label       matlab.ui.control.Label
        ASpinner_8            matlab.ui.control.Spinner
        ASpinner_8Label       matlab.ui.control.Label
        BSpinner_8            matlab.ui.control.Spinner
        BSpinner_8Label       matlab.ui.control.Label
        YSpinner_9            matlab.ui.control.Spinner
        YSpinner_9Label       matlab.ui.control.Label
        XSpinner_9            matlab.ui.control.Spinner
        XSpinner_9Label       matlab.ui.control.Label
        Label_21              matlab.ui.control.Label
        CSpinner_7            matlab.ui.control.Spinner
        CSpinner_7Label       matlab.ui.control.Label
        ZSpinner_8            matlab.ui.control.Spinner
        ZSpinner_8Label       matlab.ui.control.Label
        ASpinner_7            matlab.ui.control.Spinner
        ASpinner_7Label       matlab.ui.control.Label
        BSpinner_7            matlab.ui.control.Spinner
        BSpinner_7Label       matlab.ui.control.Label
        YSpinner_8            matlab.ui.control.Spinner
        YSpinner_8Label       matlab.ui.control.Label
        XSpinner_8            matlab.ui.control.Spinner
        XSpinner_8Label       matlab.ui.control.Label
        Label_20              matlab.ui.control.Label
        StartRunButton_4      matlab.ui.control.Button
        CSpinner_6            matlab.ui.control.Spinner
        CSpinner_6Label       matlab.ui.control.Label
        BSpinner_6            matlab.ui.control.Spinner
        BSpinner_6Label       matlab.ui.control.Label
        ASpinner_6            matlab.ui.control.Spinner
        ASpinner_6Label       matlab.ui.control.Label
        ZSpinner_7            matlab.ui.control.Spinner
        ZSpinner_7Label       matlab.ui.control.Label
        YSpinner_7            matlab.ui.control.Spinner
        YSpinner_7Label       matlab.ui.control.Label
        XSpinner_7            matlab.ui.control.Spinner
        XSpinner_7Label       matlab.ui.control.Label
        Label_6               matlab.ui.control.Label
        Tab_2                 matlab.ui.container.Tab
        StartRunButton_24     matlab.ui.control.Button
        StartRunButton_17     matlab.ui.control.Button
        StartRunButton_16     matlab.ui.control.Button
        Joint3Spinner_7       matlab.ui.control.Spinner
        Joint3SpinnerLabel_7  matlab.ui.control.Label
        Joint2Spinner_6       matlab.ui.control.Spinner
        Joint2SpinnerLabel_6  matlab.ui.control.Label
        Joint5Spinner_5       matlab.ui.control.Spinner
        Joint5SpinnerLabel_5  matlab.ui.control.Label
        Joint6Spinner_4       matlab.ui.control.Spinner
        Joint6SpinnerLabel_4  matlab.ui.control.Label
        Joint4Spinner_3       matlab.ui.control.Spinner
        Joint4SpinnerLabel_3  matlab.ui.control.Label
        Joint1Spinner_2       matlab.ui.control.Spinner
        Joint1SpinnerLabel_2  matlab.ui.control.Label
        StartRunButton_14     matlab.ui.control.Button
        StartRunButton_15     matlab.ui.control.Button
        TextArea              matlab.ui.control.TextArea
        Label_14              matlab.ui.control.Label
        Button                matlab.ui.control.Button
        Tab_3                 matlab.ui.container.Tab
        Panel_5               matlab.ui.container.Panel
        StartRunButton_32     matlab.ui.control.Button
        StartRunButton_23     matlab.ui.control.Button
        StartRunButton_21     matlab.ui.control.Button
        NJ_Z                  matlab.ui.control.Spinner
        Joint3SpinnerLabel_8  matlab.ui.control.Label
        NJ_Y                  matlab.ui.control.Spinner
        Joint2SpinnerLabel_7  matlab.ui.control.Label
        NJ_B                  matlab.ui.control.Spinner
        Joint5SpinnerLabel_6  matlab.ui.control.Label
        NJ_C                  matlab.ui.control.Spinner
        Joint6SpinnerLabel_5  matlab.ui.control.Label
        NJ_A                  matlab.ui.control.Spinner
        Joint4SpinnerLabel_4  matlab.ui.control.Label
        NJ_X                  matlab.ui.control.Spinner
        Joint1SpinnerLabel_3  matlab.ui.control.Label
        Panel                 matlab.ui.container.Panel
        StartRunButton_31     matlab.ui.control.Button
        StartRunButton_22     matlab.ui.control.Button
        StartRunButton_20     matlab.ui.control.Button
        Joint3Spinner_6       matlab.ui.control.Spinner
        Joint3SpinnerLabel_6  matlab.ui.control.Label
        Joint2Spinner_5       matlab.ui.control.Spinner
        Joint2SpinnerLabel_5  matlab.ui.control.Label
        Joint5Spinner_4       matlab.ui.control.Spinner
        Joint5SpinnerLabel_4  matlab.ui.control.Label
        Joint6Spinner_3       matlab.ui.control.Spinner
        Joint6SpinnerLabel_3  matlab.ui.control.Label
        Joint4Spinner_2       matlab.ui.control.Spinner
        Joint4SpinnerLabel_2  matlab.ui.control.Label
        Joint1Spinner         matlab.ui.control.Spinner
        Joint1SpinnerLabel    matlab.ui.control.Label
        StartRunButton        matlab.ui.control.Button
    end

    
    properties (Access = private)  %类似在此处定义局部变量，后续调用此变量时前面加个app.就OK
        robot;
%         T_A=[]; q_A=[];
        
        Joint=[0,0,0,0,0,0]; % Description


        %建立末端位姿矩阵
        position = [1,0,0,0.27;
                    0,1,0,0;
                    0,0,1,0.32;
                    0,0,0,1;] ;
        speed=0.01;
        
        %自动运行模式——直线轨迹%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
         Run_line_begin_Joint=[0 0 0 0 0 0];
         Run_line_terminus_Joint=[0 0 0 0 0 0];
         Run_line_Joint_ALL;
         Run_line_begin_position = [0,0,0,0;
                                    0,0,0,0;
                                    0,0,0,0;
                                    0,0,0,1;] ;
         Run_line_terminus_position = [0,0,0,0;
                                       0,0,0,0;
                                       0,0,0,0;
                                       0,0,0,1] ;
         %示教模式%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
         Shi_Jiao_ALL_Points = [] ;
         Shi_Jiao_Point ;
         Joints_Z;
         shi_jiao_counts = 0 ;
         shijiao_chabu_Joints_all 
         
 
         %%串口通信%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
         Port; %串口号
         StrArray; % 串口信息框刷新数组
         Shi_Jiao_TextArea;  %示教点显示框
         
         
         %正逆解模式%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
         ZJ_T ;
         ZJ_joint_all
      
         
    end
    
    methods (Access = private)
        
        function logRefresh_func (app,StrArrayNew)
           % 刷日志，串口通信显示区
            if length(app.StrArray)>=5
                app.StrArray = [];
             end
            app.StrArray = [app.StrArray, StrArrayNew]; %文本框那只能是一维的，读此处只能用" ，"            
            app.TextArea_2.Value = app.StrArray;
        end
        
        function Shi_jiao_Point_display_func(app,Shi_jiao_Point_display)
             app.Shi_Jiao_TextArea = [app.Shi_Jiao_TextArea, Shi_jiao_Point_display]
             app.TextArea.Value =  app.Shi_Jiao_TextArea ;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             %定义机器人关节连杆参数，默认为标准DH
            L1=Link('d',0.2,'a',0,'alpha',pi/2);
            L2=Link('d',0,'a',0.22,'alpha',0,'offset',pi/2);
            L3=Link('d',0,'a',0,'alpha',pi/2);
            L4=Link('d',0.27,'a',0,'alpha',-pi/2);
            L5=Link('d',0,'a',0,'alpha',-pi/2,'offset',-pi/2);
            L6=Link('d',-0.1,'a',0,'alpha',0);
            app.robot = SerialLink([L1,L2,L3,L4,L5,L6],'name','六自由度教学机器人');

        end

        % Value changed function: Joint1Spinner
        function Joint1SpinnerValueChanged(app, event)
%             Joint1 = app.Joint1Spinner.Value;
%             app.Joint(1) = Joint1*(pi/180);
%             %直接让机械手显示当前角度，但在机器人工具箱中用弧度表示的
%             view(3);
%             app.robot.plot(app.Joint);
        end

        % Button pushed function: StartRunButton
        function StartRunButtonPushed(app, event)
%             x = -pi:0.01:pi;
%             y = sin(x)+cos(x);
%             plot(app.UIAxes,x,y);
            

            %点"开始"，显示零位 
%             app.robot.plot(app.UIAxes,app.Joint);%设置显示末端轨迹点 
            hold off;
            view(3);
           app.robot.plot([0 0 0 0 0 0],'trail','.r--');%设置显示末端轨迹点

        end

        % Value changed function: Joint2Spinner_5
        function Joint2Spinner_5ValueChanged(app, event)
%             Joint2 = app.Joint2Spinner_5.Value;
%             app.Joint(2) = Joint2*(pi/180);
%             %直接让机械手显示当前角度，但在机器人工具箱中用弧度表示的
%             view(3);
%             app.robot.plot(app.Joint);
        end

        % Value changed function: Joint3Spinner_6
        function Joint3Spinner_6ValueChanged(app, event)
%             Joint3 = app.Joint3Spinner_6.Value;
%             app.Joint(3) = Joint3*(pi/180);
%             %直接让机械手显示当前角度，但在机器人工具箱中用弧度表示的
%             view(3);
%             app.robot.plot(app.Joint); 
        end

        % Value changed function: Joint4Spinner_2
        function Joint4Spinner_2ValueChanged(app, event)
%             Joint4 = app.Joint4Spinner_2.Value;
%             app.Joint(4) = Joint4*(pi/180);
%             %直接让机械手显示当前角度，但在机器人工具箱中用弧度表示的
%             view(3);
%             app.robot.plot(app.Joint);
        end

        % Value changed function: Joint5Spinner_4
        function Joint5Spinner_4ValueChanged(app, event)
%             Joint5 = app.Joint5Spinner_4.Value;
%             app.Joint(5) = Joint5*(pi/180);
%             %直接让机械手显示当前角度，但在机器人工具箱中用弧度表示的
%             view(3);
%             app.robot.plot(app.Joint);
        end

        % Value changed function: Joint6Spinner_3
        function Joint6Spinner_3ValueChanged(app, event)
%             Joint6 = app.Joint6Spinner_3.Value;
%             app.Joint(6) = Joint6*(pi/180);
%             %直接让机械手显示当前角度，但在机器人工具箱中用弧度表示的
%             view(3);
%             app.robot.plot(app.Joint);
        end

        % Callback function
        function XSpinnerValueChanged(app, event)
            X = app.XSpinner.Value;
            %将position位姿矩阵中的坐标X替换为最新值，得到新的位姿矩阵
            app.position(1, 4) = X;
            %逆解，得到新的关节角
            app.Joint = app.robot.ikine(app.position);
            %将结果plot，显示在机械手
            view(3);
            app.robot.plot(app.Joint);
        end

        % Callback function
        function YSpinnerValueChanged(app, event)
            Y = app.YSpinner.Value;
            %将position位姿矩阵中的坐标Y替换为最新值，得到新的位姿矩阵
            app.position(2, 4) = Y;
            %逆解，得到新的关节角
            app.Joint = app.robot.ikine(app.position);
            %将结果plot，显示在机械手
            view(3);
            app.robot.plot(app.Joint);
        end

        % Callback function
        function ZSpinnerValueChanged(app, event)
            Z = app.ZSpinner.Value;
            %将position位姿矩阵中的坐标Z替换为最新值，得到新的位姿矩阵
            app.position(3, 4) = Z;
            %逆解，得到新的关节角
            app.Joint = app.robot.ikine(app.position);
            %将结果plot，显示在机械手
            view(3);
            app.robot.plot(app.Joint);
        end

        % Callback function
        function ASpinnerValueChanged(app, event)
            A = app.ASpinner.Value;
            X = app.XSpinner.Value;
            Y = app.YSpinner.Value;
            Z = app.ZSpinner.Value;
            T_A = transl(X,Y,Z)*trotx(A*pi/180);
            q_A = app.robot.ikine(T_A);
            view(3);
            app.robot.plot(q_A);
        end

        % Value changed function: XSpinner_2
        function XSpinner_2ValueChanged(app, event)
            value = app.XSpinner_2.Value;
            %将position位姿矩阵中的坐标X替换为最新值，得到新的位姿矩阵
            app.Run_line_begin_position(1, 4) = value;
            %逆解，得到新的关节角
            app.Run_line_begin_Joint = app.robot.ikine(app.Run_line_begin_position);
            %将结果plot，显示在机械手
            view(3);
            app.robot.plot(app.Run_line_begin_Joint);
        end

        % Value changed function: YSpinner_2
        function YSpinner_2ValueChanged(app, event)
            value = app.YSpinner_2.Value;
            %将position位姿矩阵中的坐标Y替换为最新值，得到新的位姿矩阵
            app.Run_line_begin_position(2, 4) = value;
            %逆解，得到新的关节角
            app.Run_line_begin_Joint = app.robot.ikine(app.Run_line_begin_position);
            %将结果plot，显示在机械手
            view(3);
            app.robot.plot(app.Run_line_begin_Joint);
        end

        % Value changed function: ZSpinner_2
        function ZSpinner_2ValueChanged(app, event)
            value = app.ZSpinner_2.Value;
            %将position位姿矩阵中的坐标Z替换为最新值，得到新的位姿矩阵
            app.Run_line_begin_position(3, 4) = value;
            %逆解，得到新的关节角
            app.Run_line_begin_Joint = app.robot.ikine(app.Run_line_begin_position);
            %将结果plot，显示在机械手
            view(3);
            app.robot.plot(app.Run_line_begin_Joint);
        end

        % Value changed function: ASpinner_2
        function ASpinner_2ValueChanged(app, event)
            A = app.ASpinner_2.Value;
            X = app.XSpinner_2.Value;
            Y = app.YSpinner_2.Value;
            Z = app.ZSpinner_2.Value;
            T_A = transl(X,Y,Z)*trotx(A*pi/180);
            q_A = app.robot.ikine(T_A);
            view(3);
            app.robot.plot(q_A);
        end

        % Value changed function: BSpinner_2
        function BSpinner_2ValueChanged(app, event)
            B = app.BSpinner_2.Value;
            X = app.XSpinner_2.Value;
            Y = app.YSpinner_2.Value;
            Z = app.ZSpinner_2.Value;
            T_B = transl(X,Y,Z)*trotx(B*pi/180);
            q_B = app.robot.ikine(T_B);
            view(3);
            app.robot.plot(q_B);
        end

        % Value changed function: CSpinner_2
        function CSpinner_2ValueChanged(app, event)
            C = app.CSpinner_2.Value;
            X = app.XSpinner_2.Value;
            Y = app.YSpinner_2.Value;
            Z = app.ZSpinner_2.Value;
            T_C = transl(X,Y,Z)*trotx(C*pi/180);
            q_C = app.robot.ikine(T_C);
            view(3);
            app.robot.plot(q_C);
        end

        % Value changed function: XSpinner_3
        function XSpinner_3ValueChanged(app, event)
            value = app.XSpinner_3.Value;
            %将position位姿矩阵中的坐标X替换为最新值，得到新的位姿矩阵
            app.Run_line_terminus_position(1, 4) = value;
            %逆解，得到新的关节角
            app.Run_line_terminus_Joint = app.robot.ikine(app.Run_line_terminus_position);
            %将结果plot，显示在机械手
            view(3);
            app.robot.plot(app.Run_line_terminus_Joint);
        end

        % Value changed function: YSpinner_3
        function YSpinner_3ValueChanged(app, event)
            value = app.YSpinner_3.Value;
            %将position位姿矩阵中的坐标Y替换为最新值，得到新的位姿矩阵
            app.Run_line_terminus_position(2, 4) = value;
            %逆解，得到新的关节角
            app.Run_line_terminus_Joint = app.robot.ikine(app.Run_line_terminus_position);
            %将结果plot，显示在机械手
            view(3);
            app.robot.plot(app.Run_line_terminus_Joint);
        end

        % Value changed function: ZSpinner_3
        function ZSpinner_3ValueChanged(app, event)
            value = app.ZSpinner_3.Value;
            %将position位姿矩阵中的坐标Z替换为最新值，得到新的位姿矩阵
            app.Run_line_terminus_position(3, 4) = value;
            %逆解，得到新的关节角
            app.Run_line_terminus_Joint = app.robot.ikine(app.Run_line_terminus_position);
            %将结果plot，显示在机械手
            view(3);
            app.robot.plot(app.Run_line_terminus_Joint);
        end

        % Value changed function: ASpinner_3
        function ASpinner_3ValueChanged(app, event)
            A = app.ASpinner_3.Value;
            X = app.XSpinner_3.Value;
            Y = app.YSpinner_3.Value;
            Z = app.ZSpinner_3.Value;
            T_A = transl(X,Y,Z)*trotx(A*pi/180);
            q_A = app.robot.ikine(T_A);
            view(3);
            app.robot.plot(q_A);
        end

        % Value changed function: BSpinner_3
        function BSpinner_3ValueChanged(app, event)
            A = app.BSpinner_3.Value;
            X = app.XSpinner_3.Value;
            Y = app.YSpinner_3.Value;
            Z = app.ZSpinner_3.Value;
            T_A = transl(X,Y,Z)*trotx(A*pi/180);
            q_A = app.robot.ikine(T_A);
            view(3);
            app.robot.plot(q_A);
        end

        % Value changed function: CSpinner_3
        function CSpinner_3ValueChanged(app, event)
            A = app.CSpinner_3.Value;
            X = app.XSpinner_3.Value;
            Y = app.YSpinner_3.Value;
            Z = app.ZSpinner_3.Value;
            T_A = transl(X,Y,Z)*trotx(A*pi/180);
            q_A = app.robot.ikine(T_A);
            view(3);
            app.robot.plot(q_A);
        end

        % Callback function
        function StartRunButton_6Pushed(app, event)
            view(3); 
            app.robot.plot(app.Joint,'trail','.r--');%设置显示末端轨迹点
        end

        % Button pushed function: StartRunButton_7
        function StartRunButton_7Pushed(app, event)
            X = app.XSpinner_2.Value;
            Y = app.YSpinner_2.Value;
            Z = app.ZSpinner_2.Value;
            A = app.ASpinner_2.Value;
            B = app.BSpinner_2.Value;
            C = app.CSpinner_2.Value;
            app.Run_line_begin_position = transl(X,Y,Z)*trotx(A*pi/180)*troty(B*pi/180)*trotz(C*pi/180);
            app.Run_line_begin_Joint = app.robot.ikine(app.Run_line_begin_position);
            view(3);
            app.robot.plot(app.Joint,'trail','.r--');%机械手复位至零位
        end

        % Callback function
        function StartRunButton_8Pushed(app, event)
            X = app.XSpinner_3.Value;
            Y = app.YSpinner_3.Value;
            Z = app.ZSpinner_3.Value;
            A = app.ASpinner_3.Value;
            B = app.BSpinner_3.Value;
            C = app.CSpinner_3.Value;
            app.Run_line_terminus_position = transl(X,Y,Z)*trotx(A*pi/180)*troty(B*pi/180)*trotz(C*pi/180);
            app.Run_line_terminus_Joint = app.robot.ikine(app.Run_line_terminus_position);
            view(3);
            app.robot.plot(app.Joint,'trail','.r--');%机械手复位至零位
        end

        % Button pushed function: StartRunButton_3
        function StartRunButton_3Pushed(app, event)
            t=[0:0.1:10];      %动作时间6s，每隔1s生成一个插补点
            Run_line_position_ALL = ctraj(app.Run_line_begin_position , app.Run_line_terminus_position ,length(t)); %以位姿矩阵的形式进行轨迹规划
           app.Run_line_Joint_ALL = app.robot.ikine(Run_line_position_ALL); %逆解得关节角，控制电机用
            view(3);
            app.robot.plot(app.Run_line_Joint_ALL,'trail','.r--');  %仿真动画
%             p = transl(T_ALL);  %获取齐次变换矩阵中的平移向量XYZ
%             figure(2);
%             plot2(p, 'r');
            grid on;
        end

        % Value changed function: COM_port
        function COM_portValueChanged(app, event)

        end

        % Value changed function: BaudRate
        function BaudRateValueChanged(app, event)
%             value = app.BaudRate.Value;
%             switch value
%                    case '9600'
%                        set(app.Port,'BaudRate',9600) ;  %设置波特率
%                    case '115200'
%                        set(app.Port,'BaudRate',115200) ;  %设置波特率
%             end
        end

        % Value changed function: DataBit
        function DataBitValueChanged(app, event)
%             value = app.DataBit.Value;
%             switch value
%                 case '8'
%                     set(app.Port, 'DataBits', 8 );  %设置数据位为8位
%                 case '16'
%                     set(app.Port, 'DataBits', 16 );  %设置数据位为16位    
%             end
        end

        % Value changed function: Button_3
        function Button_3ValueChanged(app, event)
            value = app.Button_3.Value;   
            
            switch value 
                case 1 
                    
                     value2 = app.COM_port.Value;
                    
%                     save  value2;
%                     assignin("base",'COM_APP', value2  );   
              
                    switch value2
                        case 'COM9'
                            try
                                deltete (instrfindall);  %消除系统之前已经打开的串口资源
                                app.Port =serial(value2); %创建串口
                            catch
                                StrArrayNew = {'error:该串口可能已被别的设备占用'};
                                logRefresh_func(app,StrArrayNew);
                                return;
                            end
                            
                       case 'COM10'
                            try
                                deltete (instrfindall);  %消除系统之前已经打开的串口资源
                                app.Port = serial(value2); %创建串口
                            catch
                                StrArrayNew = {'error:该串口可能已被别的设备占用'};
                                logRefresh_func(app,StrArrayNew);
                                return;
                            end   
                            
                       case 'COM7'
%                             try
%                                 deltete (instrfindall);  %消除系统之前已经打开的串口资源
                                app.Port = serial(value2); %创建串口
%                             catch
%                                 StrArrayNew = {'error:该串口可能已被别的设备占用'};
%                                 logRefresh_func(app,StrArrayNew);
%                                 return;
%                             end       
                            
                       case 'COM8'
                            try
                                delete (instrfindall);  %消除系统之前已经打开的串口资源
                                app.Port = serial('COM8'); %创建串口
                            catch
                                StrArrayNew = {'error:该串口可能已被别的设备占用'};
                                logRefresh_func(app,StrArrayNew);
                                return;
                            end        
                    end
                    
                  
                    %串口前面已创建，此处先设置串口参数
                    BaudRate_Value = app.BaudRate.Value;
                    set(app.Port,'BaudRate',str2num(BaudRate_Value) ) ;  %设置波特率
                    
                    DataBit_Value = app.DataBit.Value;
                    set(app.Port,'DataBits',str2num(DataBit_Value) ) ;  %设置数据位
                    
                    set(app.Port,'inputBufferSize',4072000 ); %设置输入缓冲区为3M
                    set(app.Port,'outputBufferSize',4072000 ); %设置输出缓冲区为3M
                    set(app.Port,'Timeout', 20); %设置一次读入或写入操作完成最大时间为0.5秒
                    set(app.Port,'Terminator','LF'); %串口触发字符为"换行符"
                     
                    
                    if  app.Port.Status == 'closed'
                        try 
                            fopen(app.Port); %打开串口
                            pause(0.5);
                        catch 
                            StrArrayNew = {'error: 该串口可能已被别的设备占用'};
                            logRefresh_func(app, StrArrayNew);
                            return
                        end
                        if  app.Port.Status == "open"
                            app.Lamp.Color = "green";
                            StrArrayNew = {'msg:串口打开成功'};
                            logRefresh_func(app, StrArrayNew);
                        else
                            app.Lamp.Color = "red";
                            StrArrayNew = {'msg:串口打开失败'};
                            logRefresh_func(app, StrArrayNew);
                        end
                    else
                        app.Lamp.Color = "yellow";
                        StrArrayNew = {'error:串口被占用'};
                        logRefresh_func(app, StrArrayNew);
                    end
             case 0
                  %关闭串口
                  delete(instrfindall);
                  app.Port = [];
                  app.Lamp.Color = "red";
                  StrArrayNew = {'msg:串口已断开'};
                  logRefresh_func(app, StrArrayNew);
             end
        end

        % Button pushed function: StartRunButton_9
        function StartRunButton_9Pushed(app, event)
            %%串口发送数据按钮
            %先检查端口是否在线——串口
            if  isempty(app.Port)
                StrArrayNew = {'串口未连接'};
                logRefresh_func(app, StrArrayNew);
                return
            end
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%       
            %发送进度条
            h = waitbar(0,'数据发送中');
            
            %%%接着向串口发数据
            for i=1:1:size(app.shijiao_chabu_Joints_all,1)  %矩阵q的行数
                for j=1:1:size(app.shijiao_chabu_Joints_all,2)  %矩阵q的列数
                    if (j==size(app.shijiao_chabu_Joints_all,2)) %写完一行后换行
                        fprintf(app.Port,'%.4f\n',app.shijiao_chabu_Joints_all(i,j) );%写完每行最后一个数据后换行
                    else 
                        fprintf(app.Port,'%.4f\t',app.shijiao_chabu_Joints_all(i,j) );%每个数据间用空格隔开
                    end
                    pause(0.05);  %延时多，传输很慢；延时少，数据会乱，会丢数据
                end
                %进度条，百分制，开始进行文字的提示
                str = ['数据发送中...  ',num2str( 100*i/size(app.shijiao_chabu_Joints_all,1) ) , '%'];
                waitbar(i/size(app.shijiao_chabu_Joints_all,1) ,h, str );
            end   

            
%             %从串口读数据
%             Data = fscanf(app.Port);    
      
%             StrArrayNew = { Data };
%             logRefresh_func(app, StrArrayNew);

%             
%             %断点，从串口读数据
%               save  Data ;
%               assignin("base",'chuankou_ Data',  Data );          
%             
%             
            %断点，将示教点插补后的结果保存到工作区
              save app.shijiao_chabu_Joints_all ;
              assignin("base",'shijiao_chabu_Joints_all', app.shijiao_chabu_Joints_all  );          
            
        end

        % Button pushed function: StartRunButton_13
        function StartRunButton_13Pushed(app, event)
%                 app.StrArray = [];
        end

        % Value changed function: Joint1Spinner_2
        function Joint1Spinner_2ValueChanged(app, event)
            Joint1 = app.Joint1Spinner_2.Value;
            app.Joint(1) = Joint1*(pi/180);
            %直接让机械手显示当前角度，但在机器人工具箱中用弧度表示的
            view(3);
            app.robot.plot(app.Joint);
        end

        % Value changed function: Joint2Spinner_6
        function Joint2Spinner_6ValueChanged(app, event)
            Joint2 = app.Joint2Spinner_6.Value;
            app.Joint(2) = Joint2*(pi/180);
            %直接让机械手显示当前角度，但在机器人工具箱中用弧度表示的
            view(3);
            app.robot.plot(app.Joint);
        end

        % Value changed function: Joint3Spinner_7
        function Joint3Spinner_7ValueChanged(app, event)
            Joint3 = app.Joint3Spinner_7.Value;
            app.Joint(3) = Joint3*(pi/180);
            %直接让机械手显示当前角度，但在机器人工具箱中用弧度表示的
            view(3);
            app.robot.plot(app.Joint); 
        end

        % Value changed function: Joint4Spinner_3
        function Joint4Spinner_3ValueChanged(app, event)
            Joint4 = app.Joint4Spinner_3.Value;
            app.Joint(4) = Joint4*(pi/180);
            %直接让机械手显示当前角度，但在机器人工具箱中用弧度表示的
            view(3);
            app.robot.plot(app.Joint);
        end

        % Value changed function: Joint5Spinner_5
        function Joint5Spinner_5ValueChanged(app, event)
            Joint5 = app.Joint5Spinner_5.Value;
            app.Joint(5) = Joint5*(pi/180);
            %直接让机械手显示当前角度，但在机器人工具箱中用弧度表示的
            view(3);
            app.robot.plot(app.Joint);
        end

        % Value changed function: Joint6Spinner_4
        function Joint6Spinner_4ValueChanged(app, event)
            Joint6 = app.Joint6Spinner_4.Value;
            app.Joint(6) = Joint6*(pi/180);
            %直接让机械手显示当前角度，但在机器人工具箱中用弧度表示的
            view(3);
            app.robot.plot(app.Joint);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %先计数，示教点个数+1：
            app.shi_jiao_counts = app.shi_jiao_counts +1;
            %先记录示教点时六个关节的角度，并转换为弧度制
            app.Shi_Jiao_Point = [app.Joint1Spinner_2.Value,app.Joint2Spinner_6.Value,app.Joint3Spinner_7.Value,app.Joint4Spinner_3.Value,app.Joint5Spinner_5.Value,app.Joint6Spinner_4.Value]*pi/180;   
            %将新的示教点加入示教点矩阵汇总
            app.Shi_Jiao_ALL_Points = [app.Shi_Jiao_ALL_Points;app.Shi_Jiao_Point]; %分行排列
            %在界面显示框中显示示教点
            Shi_jiao_Point_display = {num2str(app.Shi_Jiao_Point)};
            Shi_jiao_Point_display_func(app, Shi_jiao_Point_display);
            %在图上显示出示教点
            hold on;
            T = app.robot.fkine(app.Shi_Jiao_Point);%SE3类型
            T1 = tr2rt (T) ;%获得T的旋转矩阵
            T2 = transl(T); %获得T的平移坐标XYZ
            T3 = [T1,T2';[0 0 0 1]];%转为4x4的齐次变换矩阵
            plot3( T3(1,4),T3(2,4),T3(3,4),'r--o');  
            
%             %断点，保存到工作区
%               save  T3  ;
%               assignin("base",'T_APP',  T3  ); 
%               
%               save app.Shi_Jiao_Point  ;
%               assignin("base",'Shi_Jiao_Point', app.Shi_Jiao_Point  ); 
%               
%               save app.Shi_Jiao_ALL_Points  ;
%               assignin("base",'Shi_Jiao_ALL_Points', app.Shi_Jiao_ALL_Points  ); 
%               
            
        end

        % Button pushed function: StartRunButton_2
        function StartRunButton_2Pushed(app, event)
            app.robot.plot([0 0 0 0 0 0]);%机械手复位至零位
        end

        % Button pushed function: StartRunButton_14
        function StartRunButton_14Pushed(app, event)
                app.TextArea.Value = num2str([])  ; %清空示教点显示框
                app.Shi_Jiao_ALL_Points = []; %清空示教点汇总矩阵
                app.robot.plot([0 0 0 0 0 0]); %机械手回零位
                                       %如何删除plot3的示教点？
                
                %打断点
                save app.Shi_Jiao_ALL_Points  ;
                assignin("base",'Shi_Jiao_ALL_Points', app.Shi_Jiao_ALL_Points  ); 
               
        end

        % Button pushed function: StartRunButton_15
        function StartRunButton_15Pushed(app, event)
                %先计数，示教点个数-1
                app.shi_jiao_counts = app.shi_jiao_counts - 1;
                line_number = size(app.Shi_Jiao_ALL_Points,1);  %获取行数
                app.Shi_Jiao_ALL_Points(line_number,:) = []; %删除最后一行
                %删除示教点，实际上是把再画个白色示教点覆盖，因为专门的删除函数
                hold on;
                T = app.robot.fkine(app.Shi_Jiao_Point);%SE3类型
                T1 = tr2rt (T) ;%获得T的旋转矩阵
                T2 = transl(T); %获得T的平移坐标XYZ
                T3 = [T1,T2';[0 0 0 1]];%转为4x4的齐次变换矩阵
                plot3( T3(1,4),T3(2,4),T3(3,4),'w--o');                
        end

        % Button pushed function: StartRunButton_16
        function StartRunButton_16Pushed(app, event)
               %仿真动作示教轨迹 
               view(3);
               app.robot.plot( app.shijiao_chabu_Joints_all, 'trail', '.r--' );  % 仿真动画  
        end

        % Button pushed function: StartRunButton_17
        function StartRunButton_17Pushed(app, event)
             %向串口发送命令"S"
             fprintf(app.Port,'%s','S');
        end

        % Callback function
        function StartRunButton_18Pushed(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%% 第一段：从零位至起点，用ctraj函数 ,注：零位的坐标是（0.27, 0, 0.32）
%             T0 = transl(0.27, 0, 0.32); %零位
%             T1 = transl(0.3, 0.3, 0.4)*troty(-pi/2);
%             t1 = [0:0.01:1]; %跟后面轨迹保持一致，生成相同插补点，方便后面处理
%             %%ctraj()部分
%             Ts_1 = ctraj(T0, T1, length(t1) ) ;
%             Joints_1 = app.robot.ikine(Ts_1);
%             
%             % robot.plot(Joints_1,'trail', '.r--');
%             % hold on; %为后续继续画轨迹做准备

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %% 第二段：走目标轨迹，此处为走Z型，此处用梯形加减速
            %第2点，从起点（0.3， 0.3， 0.4）走至（0.3，-0.3,0.4）
            wpts = [ 0.3 ,  0.3  , 0.3  , 0.3  ;
                     0.3 ,  -0.3 , 0.3  , -0.3 ;
                     0.4 ,  0.4  , -0.1 , -0.1 ];  
            %初始时间是0s，终点时间是1s
            tpts = [0:3];
            %仿真所用的时长为1s，仿真时间间隔为0.1s
            tvec = 0:0.01:3;
            %%用梯形加减速trapveltraj()
            [q,qd,qdd,tvec,pp] = trapveltraj(wpts,length(tvec));
            
            for i=1:1:length(tvec)
                Ts_2(:,:,i) = transl(q(:,i))*troty(-pi/2);  %末端位姿绕y轴转过-90°，使末端正对待刷墙面 
            end
            app.Joints_Z = app.robot.ikine(Ts_2);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%% 第三段，从轨迹终点返回至零位
%             T4 = transl(0.3, -0.3,-0.1)*troty(-pi/2); %终点
%             T0 = transl(0.27, 0, 0.32); %零位
%             t5 = [0:0.01:1]; %跟前面轨迹保持一致，生成相同插补点，方便处理
%             Ts_5 = ctraj(T4, T0, length(t5) ) ;
%             Joints_5 = app.robot.ikine(Ts_5);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%             %将所有插补好后的关节角合并，并用plot展示
%             Joints_ALL = [Joints_1; Joints_2; Joints_3; Joints_4; Joints_5] ;
%             % robot.plot(Joints_ALL, 'trail', '.r--');

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%             %拟合角位移与t，之后再求导得速度与t
%             v_p_all = [];
%             for i = 0:1:4
%                 for j = 1:1:6
%                     a =1+i*101; b =101+i*101;
%                     theta_p = polyfit(t1,Joints_ALL(a:b,j),5); % 拟合角位移与t
%                     %求导的速度与t的系数，为不绕圈，此处直接写系数
%                     v_p(1) = theta_p(1)*5;
%                     v_p(2) = theta_p(2)*4;
%                     v_p(3) = theta_p(3)*3;
%                     v_p(4) = theta_p(4)*2;
%                     v_p(5) = theta_p(5)*1;
%                     v_p_all = [v_p_all; v_p];
%                 end
%             end

            %%进度条
            h = waitbar(0.1,'正在发送');%初始值0.1
            
            %%%%%为串口通信做准备
            for i=1:1:size(app.Joints_Z,1)  %矩阵q的行数
                for j=1:1:size(app.Joints_Z,2)  %矩阵q的列数
                    if (j==size(app.Joints_Z,2)) %写完一行后换行
                        fprintf(app.Port,'%.4f\n',app.Joints_Z(i,j) );%写完每行最后一个数据后换行
                    else 
                        fprintf(app.Port,'%.4f\t', app.Joints_Z(i,j) );%每个数据间用空格隔开  
                    end
                    pause(0.01);  %延时多，传输很慢；延时少，数据会乱，会丢数据
                    
                    %进度条，转成百分制，并进行文字的提示
                    str = ['发送中...' , num2str(i/size(app.Joints_Z,1)*100 ), '%'];
                    waitbar( i/size(app.Joints_Z,1), h, str );
                    
                end
            end           
            
            %从串口读数据
            Data = fscanf(app.Port);    
      
            StrArrayNew = { Data };
            logRefresh_func(app, StrArrayNew);
            
            %断点，保存到工作区
              save Data ;
              assignin("base",'Data_APP', Data  );   
        end

        % Button pushed function: StartRunButton_12
        function StartRunButton_12Pushed(app, event)
            
        end

        % Button pushed function: StartRunButton_20
        function StartRunButton_20Pushed(app, event)
            ZJ_joint1 = app.Joint1Spinner.Value;
            ZJ_joint2 = app.Joint2Spinner_5.Value;
            ZJ_joint3 = app.Joint3Spinner_6.Value;
            ZJ_joint4 = app.Joint4Spinner_2.Value;
            ZJ_joint5 = app.Joint5Spinner_4.Value;
            ZJ_joint6 = app.Joint6Spinner_3.Value;
            app.ZJ_joint_all = [ZJ_joint1,ZJ_joint2,ZJ_joint3,ZJ_joint4,ZJ_joint5,ZJ_joint6]*(pi/180);
            app.ZJ_T = app.robot.fkine(app.ZJ_joint_all); %SE3类型
            T1 = tr2rt (app.ZJ_T) ;%获得T的旋转矩阵
            T2 = transl(app.ZJ_T); %获得T的平移坐标XYZ
            app.ZJ_T = [T1,T2';[0 0 0 1]];%转为4x4的齐次变换矩阵
            %%%从T中提取位置和姿态
            app.NJ_X.Value = app.ZJ_T(1,4);
            app.NJ_Y.Value = app.ZJ_T(2,4);
            app.NJ_Z.Value = app.ZJ_T(3,4);
            Eul = rotm2eul(app.ZJ_T(1:3,1:3));
            app.NJ_A.Value = Eul(1,1);
            app.NJ_B.Value = Eul(1,2);
            app.NJ_C.Value = Eul(1,3);
            
        end

        % Button pushed function: StartRunButton_22
        function StartRunButton_22Pushed(app, event)
            app.robot.plot(app.ZJ_joint_all);
        end

        % Button pushed function: StartRunButton_21
        function StartRunButton_21Pushed(app, event)
            X = app.NJ_X.Value;
            Y = app.NJ_Y.Value;
            Z = app.NJ_Z.Value;
            A = (app.NJ_A.Value)*(pi/180);
            B = (app.NJ_B.Value)*(pi/180);
            C = (app.NJ_C.Value)*(pi/180);
            T = transl(X,Y,Z)*trotx(A)*troty(B)*trotz(C);
            app.ZJ_joint_all = (app.robot.ikine(T))*(180/pi);
            app.Joint1Spinner.Value = app.ZJ_joint_all(1,1);
            app.Joint2Spinner_5.Value = app.ZJ_joint_all(1,2);
            app.Joint3Spinner_6.Value = app.ZJ_joint_all(1,3);
            app.Joint4Spinner_2.Value = app.ZJ_joint_all(1,4);
            app.Joint5Spinner_4.Value = app.ZJ_joint_all(1,5);
            app.Joint6Spinner_3.Value = app.ZJ_joint_all(1,6);
            
            
        end

        % Button pushed function: StartRunButton_23
        function StartRunButton_23Pushed(app, event)
            app.robot.plot(app.ZJ_joint_all);
        end

        % Button pushed function: StartRunButton_24
        function StartRunButton_24Pushed(app, event)
            %直接调用app.Shi_Jiao_ALL_Points
            %fkine（）正解出来是SE3类型，无奈只能先将其转为4x4double
           for i = 1:1:size(app.Shi_Jiao_ALL_Points,1)
                 T = app.robot.fkine( app.Shi_Jiao_ALL_Points(i,:) );
                 T1 = tr2rt (T) ;%获得T的旋转矩阵
                 T2 = transl(T); %获得T的平移坐标XYZ
                 T_shijiao(:,:,i) = [T1,T2';[0 0 0 1]];%合并为4x4的齐次变换矩阵
           end
            %下面对示教点进行插补
            T_all = [];
            for i = 1:1:size(app.Shi_Jiao_ALL_Points,1)-1  
               %直接采用ctraj对两点之间进行两两插补
               t = [0:0.01:1];
               T_chabu = ctraj(T_shijiao(:,:,i) , T_shijiao(:,:,i+1) , length(t));
               T_all = cat( 3, T_all , T_chabu ) ;  %将两个三维矩阵进行拼接
            end
            app.shijiao_chabu_Joints_all = app.robot.ikine( T_all );
            
        end

        % Button pushed function: StartRunButton_11
        function StartRunButton_11Pushed(app, event)
            %向串口发送命令"Q"
             fprintf(app.Port,'%s','Q');
        end

        % Button pushed function: StartRunButton_10
        function StartRunButton_10Pushed(app, event)
            %向串口发送命令"F"
             fprintf(app.Port,'%s','F');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Title = '                                                               六自由度教学机器人操控界面';
            app.StartRunButton = uibutton(app.Panel_3, 'push');
            app.StartRunButton.ButtonPushedFcn = createCallbackFcn(app, @StartRunButtonPushed, true);
            app.StartRunButton.Text = '仿真启动';
            app.TabGroup = uitabgroup(app.Panel_3);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '点位模式';
            app.TabGroup2 = uitabgroup(app.Tab);
            app.Tab_4 = uitab(app.TabGroup2);
            app.Tab_4.Title = '直线轨迹';
            app.Label = uilabel(app.Tab_4);
            app.Label.Text = '起    点';
            app.XSpinner_2Label = uilabel(app.Tab_4);
            app.XSpinner_2Label.Text = 'X';
            app.XSpinner_2 = uispinner(app.Tab_4);
            app.XSpinner_2.Step = 0.01;
            app.XSpinner_2.Limits = [-0.4 0.4];
            app.XSpinner_2.ValueChangedFcn = createCallbackFcn(app, @XSpinner_2ValueChanged, true);
            app.XSpinner_2.Value = 0.2;
            app.YSpinner_2Label = uilabel(app.Tab_4);
            app.YSpinner_2Label.Text = 'Y';
            app.YSpinner_2 = uispinner(app.Tab_4);
            app.YSpinner_2.Step = 0.01;
            app.YSpinner_2.Limits = [-0.5 0.5];
            app.YSpinner_2.ValueChangedFcn = createCallbackFcn(app, @YSpinner_2ValueChanged, true);
            app.ZSpinner_2Label = uilabel(app.Tab_4);
            app.ZSpinner_2Label.Text = 'Z';
            app.ZSpinner_2 = uispinner(app.Tab_4);
            app.ZSpinner_2.Step = 0.01;
            app.ZSpinner_2.Limits = [-0.4 0.4];
            app.ZSpinner_2.ValueChangedFcn = createCallbackFcn(app, @ZSpinner_2ValueChanged, true);
            app.ASpinner_2Label = uilabel(app.Tab_4);
            app.ASpinner_2Label.Text = 'A';
            app.ASpinner_2 = uispinner(app.Tab_4);
            app.ASpinner_2.Limits = [-90 90];
            app.ASpinner_2.ValueChangedFcn = createCallbackFcn(app, @ASpinner_2ValueChanged, true);
            app.BSpinner_2Label = uilabel(app.Tab_4);
            app.BSpinner_2Label.Text = 'B';
            app.BSpinner_2 = uispinner(app.Tab_4);
            app.BSpinner_2.Limits = [-90 90];
            app.BSpinner_2.ValueChangedFcn = createCallbackFcn(app, @BSpinner_2ValueChanged, true);
            app.CSpinner_2Label = uilabel(app.Tab_4);
            app.CSpinner_2Label.Text = 'C';
            app.CSpinner_2 = uispinner(app.Tab_4);
            app.CSpinner_2.ValueChangedFcn = createCallbackFcn(app, @CSpinner_2ValueChanged, true);
            app.Label_2 = uilabel(app.Tab_4);
            app.Label_2.Text = '终    点';
            app.XSpinner_3Label = uilabel(app.Tab_4);
            app.XSpinner_3Label.Text = 'X';
            app.XSpinner_3 = uispinner(app.Tab_4);
            app.XSpinner_3.Step = 0.01;
            app.XSpinner_3.Limits = [-0.4 0.4];
            app.XSpinner_3.ValueChangedFcn = createCallbackFcn(app, @XSpinner_3ValueChanged, true);
            app.XSpinner_3.Value = 0.2;
            app.YSpinner_3Label = uilabel(app.Tab_4);
            app.YSpinner_3Label.Text = 'Y';
            app.YSpinner_3 = uispinner(app.Tab_4);
            app.YSpinner_3.Step = 0.01;
            app.YSpinner_3.Limits = [-0.5 0.5];
            app.YSpinner_3.ValueChangedFcn = createCallbackFcn(app, @YSpinner_3ValueChanged, true);
            app.ZSpinner_3Label = uilabel(app.Tab_4);
            app.ZSpinner_3Label.Text = 'Z';
            app.ZSpinner_3 = uispinner(app.Tab_4);
            app.ZSpinner_3.Step = 0.01;
            app.ZSpinner_3.Limits = [-0.4 0.4];
            app.ZSpinner_3.ValueChangedFcn = createCallbackFcn(app, @ZSpinner_3ValueChanged, true);
            app.ASpinner_3Label = uilabel(app.Tab_4);
            app.ASpinner_3Label.Text = 'A';
            app.ASpinner_3 = uispinner(app.Tab_4);
            app.ASpinner_3.Limits = [-90 90];
            app.ASpinner_3.ValueChangedFcn = createCallbackFcn(app, @ASpinner_3ValueChanged, true);
            app.BSpinner_3Label = uilabel(app.Tab_4);
            app.BSpinner_3Label.Text = 'B';
            app.BSpinner_3 = uispinner(app.Tab_4);
            app.BSpinner_3.Limits = [-90 90];
            app.BSpinner_3.ValueChangedFcn = createCallbackFcn(app, @BSpinner_3ValueChanged, true);
            app.CSpinner_3Label = uilabel(app.Tab_4);
            app.CSpinner_3Label.Text = 'C';
            app.CSpinner_3 = uispinner(app.Tab_4);
            app.CSpinner_3.Limits = [-90 90];
            app.CSpinner_3.ValueChangedFcn = createCallbackFcn(app, @CSpinner_3ValueChanged, true);
            app.StartRunButton_3 = uibutton(app.Tab_4, 'push');
            app.StartRunButton_3.ButtonPushedFcn = createCallbackFcn(app, @StartRunButton_3Pushed, true);
            app.StartRunButton_3.Text = '仿   真';
            app.StartRunButton_7 = uibutton(app.Tab_4, 'push');
            app.StartRunButton_7.ButtonPushedFcn = createCallbackFcn(app, @StartRunButton_7Pushed, true);
            app.StartRunButton_7.Text = '确定';
            app.StartRunButton_12 = uibutton(app.Tab_4, 'push');
            app.StartRunButton_12.ButtonPushedFcn = createCallbackFcn(app, @StartRunButton_12Pushed, true);
            app.StartRunButton_12.Text = '实物动作';
            app.StartRunButton_28 = uibutton(app.Tab_4, 'push');
            app.StartRunButton_28.Text = '插补计算';
            app.Tab_5 = uitab(app.TabGroup2);
            app.Tab_5.Title = '圆弧轨迹';
            app.Label_6 = uilabel(app.Tab_5);
            app.Label_6.Text = '起  点';
            app.XSpinner_7Label = uilabel(app.Tab_5);
            app.XSpinner_7Label.Text = 'X';
            app.XSpinner_7 = uispinner(app.Tab_5);
            app.YSpinner_7Label = uilabel(app.Tab_5);
            app.YSpinner_7Label.Text = 'Y';
            app.YSpinner_7 = uispinner(app.Tab_5);
            app.ZSpinner_7Label = uilabel(app.Tab_5);
            app.ZSpinner_7Label.Text = 'Z';
            app.ZSpinner_7 = uispinner(app.Tab_5);
            app.ASpinner_6Label = uilabel(app.Tab_5);
            app.ASpinner_6Label.Text = 'A';
            app.ASpinner_6 = uispinner(app.Tab_5);
            app.BSpinner_6Label = uilabel(app.Tab_5);
            app.BSpinner_6Label.Text = 'B';
            app.BSpinner_6 = uispinner(app.Tab_5);
            app.CSpinner_6Label = uilabel(app.Tab_5);
            app.CSpinner_6Label.Text = 'C';
            app.CSpinner_6 = uispinner(app.Tab_5);
            app.StartRunButton_4 = uibutton(app.Tab_5, 'push');
            app.StartRunButton_4.Text = '插补计算';
            app.Label_20 = uilabel(app.Tab_5);
            app.Label_20.Text = '中间点';
            app.XSpinner_8Label = uilabel(app.Tab_5);
            app.XSpinner_8Label.Text = 'X';
            app.XSpinner_8 = uispinner(app.Tab_5);
            app.YSpinner_8Label = uilabel(app.Tab_5);
            app.YSpinner_8Label.Text = 'Y';
            app.YSpinner_8 = uispinner(app.Tab_5);
            app.BSpinner_7Label = uilabel(app.Tab_5);
            app.BSpinner_7Label.Text = 'B';
            app.BSpinner_7 = uispinner(app.Tab_5);
            app.ASpinner_7Label = uilabel(app.Tab_5);
            app.ASpinner_7Label.Text = 'A';
            app.ASpinner_7 = uispinner(app.Tab_5);
            app.ZSpinner_8Label = uilabel(app.Tab_5);
            app.ZSpinner_8Label.Text = 'Z';
            app.ZSpinner_8 = uispinner(app.Tab_5);
            app.CSpinner_7Label = uilabel(app.Tab_5);
            app.CSpinner_7Label.Text = 'C';
            app.CSpinner_7 = uispinner(app.Tab_5);
            app.Label_21 = uilabel(app.Tab_5);
            app.Label_21.Text = '终  点';
            app.XSpinner_9Label = uilabel(app.Tab_5);
            app.XSpinner_9Label.Text = 'X';
            app.XSpinner_9 = uispinner(app.Tab_5);
            app.YSpinner_9Label = uilabel(app.Tab_5);
            app.YSpinner_9Label.Text = 'Y';
            app.YSpinner_9 = uispinner(app.Tab_5);
            app.BSpinner_8Label = uilabel(app.Tab_5);
            app.BSpinner_8Label.Text = 'B';
            app.BSpinner_8 = uispinner(app.Tab_5);
            app.ASpinner_8Label = uilabel(app.Tab_5);
            app.ASpinner_8Label.Text = 'A';
            app.ASpinner_8 = uispinner(app.Tab_5);
            app.ZSpinner_9Label = uilabel(app.Tab_5);
            app.ZSpinner_9Label.Text = 'Z';
            app.ZSpinner_9 = uispinner(app.Tab_5);
            app.CSpinner_8Label = uilabel(app.Tab_5);
            app.CSpinner_8Label.Text = 'C';
            app.CSpinner_8 = uispinner(app.Tab_5);
            app.StartRunButton_19 = uibutton(app.Tab_5, 'push');
            app.StartRunButton_19.Text = '确 定';
            app.StartRunButton_29 = uibutton(app.Tab_5, 'push');
            app.StartRunButton_29.Text = '仿   真';
            app.StartRunButton_30 = uibutton(app.Tab_5, 'push');
            app.StartRunButton_30.Text = '实物动作';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '示教模式';
            app.Button = uibutton(app.Tab_2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '确认示教点';
            app.Label_14 = uilabel(app.Tab_2);
            app.Label_14.Text = '示   教   点';
            app.TextArea = uitextarea(app.Tab_2);
            app.StartRunButton_15 = uibutton(app.Tab_2, 'push');
            app.StartRunButton_15.ButtonPushedFcn = createCallbackFcn(app, @StartRunButton_15Pushed, true);
            app.StartRunButton_15.Text = '删除示教点';
            app.StartRunButton_14 = uibutton(app.Tab_2, 'push');
            app.StartRunButton_14.ButtonPushedFcn = createCallbackFcn(app, @StartRunButton_14Pushed, true);
            app.StartRunButton_14.Text = '清   空';
            app.Joint1SpinnerLabel_2 = uilabel(app.Tab_2);
            app.Joint1SpinnerLabel_2.Text = 'Joint1';
            app.Joint1Spinner_2 = uispinner(app.Tab_2);
            app.Joint1Spinner_2.ValueChangedFcn = createCallbackFcn(app, @Joint1Spinner_2ValueChanged, true);
            app.Joint4SpinnerLabel_3 = uilabel(app.Tab_2);
            app.Joint4SpinnerLabel_3.Text = 'Joint4';
            app.Joint4Spinner_3 = uispinner(app.Tab_2);
            app.Joint4Spinner_3.ValueChangedFcn = createCallbackFcn(app, @Joint4Spinner_3ValueChanged, true);
            app.Joint6SpinnerLabel_4 = uilabel(app.Tab_2);
            app.Joint6SpinnerLabel_4.Text = 'Joint6';
            app.Joint6Spinner_4 = uispinner(app.Tab_2);
            app.Joint6Spinner_4.ValueChangedFcn = createCallbackFcn(app, @Joint6Spinner_4ValueChanged, true);
            app.Joint5SpinnerLabel_5 = uilabel(app.Tab_2);
            app.Joint5SpinnerLabel_5.Text = 'Joint2';
            app.Joint5Spinner_5 = uispinner(app.Tab_2);
            app.Joint5Spinner_5.ValueChangedFcn = createCallbackFcn(app, @Joint5Spinner_5ValueChanged, true);
            app.Joint2SpinnerLabel_6 = uilabel(app.Tab_2);
            app.Joint2SpinnerLabel_6.Text = 'Joint5';
            app.Joint2Spinner_6 = uispinner(app.Tab_2);
            app.Joint2Spinner_6.ValueChangedFcn = createCallbackFcn(app, @Joint2Spinner_6ValueChanged, true);
            app.Joint3SpinnerLabel_7 = uilabel(app.Tab_2);
            app.Joint3SpinnerLabel_7.Text = 'Joint3';
            app.Joint3Spinner_7 = uispinner(app.Tab_2);
            app.Joint3Spinner_7.ValueChangedFcn = createCallbackFcn(app, @Joint3Spinner_7ValueChanged, true);
            app.StartRunButton_16 = uibutton(app.Tab_2, 'push');
            app.StartRunButton_16.ButtonPushedFcn = createCallbackFcn(app, @StartRunButton_16Pushed, true);
            app.StartRunButton_16.Text = '仿   真';
            app.StartRunButton_17 = uibutton(app.Tab_2, 'push');
            app.StartRunButton_17.ButtonPushedFcn = createCallbackFcn(app, @StartRunButton_17Pushed, true);
            app.StartRunButton_17.Text = '实物动作';
            app.StartRunButton_24 = uibutton(app.Tab_2, 'push');
            app.StartRunButton_24.ButtonPushedFcn = createCallbackFcn(app, @StartRunButton_24Pushed, true);
            app.StartRunButton_24.Text = '插补计算';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '正逆解模式';
            app.Panel = uipanel(app.Tab_3);
            app.Panel.Title = '正解：输入关节角';
            app.Joint1SpinnerLabel = uilabel(app.Panel);
            app.Joint1SpinnerLabel.Text = 'Joint1';
            app.Joint1Spinner = uispinner(app.Panel);
            app.Joint1Spinner.ValueChangedFcn = createCallbackFcn(app, @Joint1SpinnerValueChanged, true);
            app.Joint4SpinnerLabel_2 = uilabel(app.Panel);
            app.Joint4SpinnerLabel_2.Text = 'Joint4';
            app.Joint4Spinner_2 = uispinner(app.Panel);
            app.Joint4Spinner_2.ValueChangedFcn = createCallbackFcn(app, @Joint4Spinner_2ValueChanged, true);
            app.Joint6SpinnerLabel_3 = uilabel(app.Panel);
            app.Joint6SpinnerLabel_3.Text = 'Joint6';
            app.Joint6Spinner_3 = uispinner(app.Panel);
            app.Joint6Spinner_3.ValueChangedFcn = createCallbackFcn(app, @Joint6Spinner_3ValueChanged, true);
            app.Joint5SpinnerLabel_4 = uilabel(app.Panel);
            app.Joint5SpinnerLabel_4.Text = 'Joint2';
            app.Joint5Spinner_4 = uispinner(app.Panel);
            app.Joint5Spinner_4.ValueChangedFcn = createCallbackFcn(app, @Joint5Spinner_4ValueChanged, true);
            app.Joint2SpinnerLabel_5 = uilabel(app.Panel);
            app.Joint2SpinnerLabel_5.Text = 'Joint5';
            app.Joint2Spinner_5 = uispinner(app.Panel);
            app.Joint2Spinner_5.ValueChangedFcn = createCallbackFcn(app, @Joint2Spinner_5ValueChanged, true);
            app.Joint3SpinnerLabel_6 = uilabel(app.Panel);
            app.Joint3SpinnerLabel_6.Text = 'Joint3';
            app.Joint3Spinner_6 = uispinner(app.Panel);
            app.Joint3Spinner_6.ValueChangedFcn = createCallbackFcn(app, @Joint3Spinner_6ValueChanged, true);
            app.StartRunButton_20 = uibutton(app.Panel, 'push');
            app.StartRunButton_20.ButtonPushedFcn = createCallbackFcn(app, @StartRunButton_20Pushed, true);
            app.StartRunButton_20.Text = '正   解';
            app.StartRunButton_22 = uibutton(app.Panel, 'push');
            app.StartRunButton_22.ButtonPushedFcn = createCallbackFcn(app, @StartRunButton_22Pushed, true);
            app.StartRunButton_22.Text = '仿真显示';
            app.StartRunButton_31 = uibutton(app.Panel, 'push');
            app.StartRunButton_31.Text = '实物动作';
            app.Panel_5 = uipanel(app.Tab_3);
            app.Panel_5.Title = '逆解：输入位姿';
            app.Joint1SpinnerLabel_3 = uilabel(app.Panel_5);
            app.Joint1SpinnerLabel_3.Text = 'X';
            app.NJ_X = uispinner(app.Panel_5);
            app.NJ_X.Step = 0.01;
            app.Joint4SpinnerLabel_4 = uilabel(app.Panel_5);
            app.Joint4SpinnerLabel_4.Text = 'A';
            app.NJ_A = uispinner(app.Panel_5);
            app.NJ_A.Step = 0.01;
            app.Joint6SpinnerLabel_5 = uilabel(app.Panel_5);
            app.Joint6SpinnerLabel_5.Text = 'C';
            app.NJ_C = uispinner(app.Panel_5);
            app.NJ_C.Step = 0.01;
            app.Joint5SpinnerLabel_6 = uilabel(app.Panel_5);
            app.Joint5SpinnerLabel_6.Text = 'Y';
            app.NJ_B = uispinner(app.Panel_5);
            app.NJ_B.Step = 0.01;
            app.Joint2SpinnerLabel_7 = uilabel(app.Panel_5);
            app.Joint2SpinnerLabel_7.Text = 'B';
            app.NJ_Y = uispinner(app.Panel_5);
            app.NJ_Y.Step = 0.01;
            app.Joint3SpinnerLabel_8 = uilabel(app.Panel_5);
            app.Joint3SpinnerLabel_8.Text = 'Z';
            app.NJ_Z = uispinner(app.Panel_5);
            app.NJ_Z.Step = 0.01;
            app.StartRunButton_21 = uibutton(app.Panel_5, 'push');
            app.StartRunButton_21.ButtonPushedFcn = createCallbackFcn(app, @StartRunButton_21Pushed, true);
            app.StartRunButton_21.Text = '逆   解';
            app.StartRunButton_23 = uibutton(app.Panel_5, 'push');
            app.StartRunButton_23.ButtonPushedFcn = createCallbackFcn(app, @StartRunButton_23Pushed, true);
            app.StartRunButton_23.Text = '仿真显示';
            app.StartRunButton_32 = uibutton(app.Panel_5, 'push');
            app.StartRunButton_32.Text = '实物动作';
            app.StartRunButton_2 = uibutton(app.Panel_3, 'push');
            app.StartRunButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartRunButton_2Pushed, true);
            app.StartRunButton_2.Text = '仿真复位';
            app.Panel_4 = uipanel(app.Panel_3);
            app.Panel_4.Title = '串口通信';
            app.Label_15 = uilabel(app.Panel_4);
            app.Label_15.Text = '串口号：';
            app.COM_port = uidropdown(app.Panel_4);
            app.COM_port.Items = {'COM7', 'COM8', 'COM9', 'COM10'};
            app.COM_port.ValueChangedFcn = createCallbackFcn(app, @COM_portValueChanged, true);
            app.COM_port.Value = 'COM8';
            app.Label_16 = uilabel(app.Panel_4);
            app.Label_16.Text = '波特率：';
            app.BaudRate = uidropdown(app.Panel_4);
            app.BaudRate.Items = {'230400', '115200'};
            app.BaudRate.ValueChangedFcn = createCallbackFcn(app, @BaudRateValueChanged, true);
            app.BaudRate.Value = '115200';
            app.Label_17 = uilabel(app.Panel_4);
            app.Label_17.Text = '数据位：';
            app.DataBit = uidropdown(app.Panel_4);
            app.DataBit.Items = {'8', '16'};
            app.DataBit.ValueChangedFcn = createCallbackFcn(app, @DataBitValueChanged, true);
            app.DataBit.Value = '8';
            app.Label_18 = uilabel(app.Panel_4);
            app.Label_18.Text = '校验位：';
            app.DropDown_4 = uidropdown(app.Panel_4);
            app.DropDown_4.Items = {'None'};
            app.DropDown_4.Value = 'None';
            app.Lamp = uilamp(app.Panel_4);
            app.Button_3 = uibutton(app.Panel_4, 'state');
            app.Button_3.ValueChangedFcn = createCallbackFcn(app, @Button_3ValueChanged, true);
            app.Button_3.Text = '打开/关闭串口';
            app.StartRunButton_9 = uibutton(app.Panel_4, 'push');
            app.StartRunButton_9.ButtonPushedFcn = createCallbackFcn(app, @StartRunButton_9Pushed, true);
            app.StartRunButton_9.Text = '发    送';
            app.StartRunButton_13 = uibutton(app.Panel_4, 'push');
            app.StartRunButton_13.ButtonPushedFcn = createCallbackFcn(app, @StartRunButton_13Pushed, true);
            app.StartRunButton_13.Text = '清   空';
            app.StartRunButton_11 = uibutton(app.Panel_3, 'push');
            app.StartRunButton_11.ButtonPushedFcn = createCallbackFcn(app, @StartRunButton_11Pushed, true);
            app.StartRunButton_11.Text = '实物启动';
            app.Label_19 = uilabel(app.Panel_3);
            app.Label_19.Text = '串  口  信  息';
            app.TextArea_2 = uitextarea(app.Panel_3);
            app.StartRunButton_10 = uibutton(app.Panel_3, 'push');
            app.StartRunButton_10.ButtonPushedFcn = createCallbackFcn(app, @StartRunButton_10Pushed, true);
            app.StartRunButton_10.Text = '实物复位';
            app.StartRunButton_25 = uibutton(app.Panel_3, 'push');
            app.StartRunButton_25.Text = '低速';
            app.StartRunButton_26 = uibutton(app.Panel_3, 'push');
            app.StartRunButton_26.Text = '高速';
            app.StartRunButton_27 = uibutton(app.Panel_3, 'push');
            app.StartRunButton_27.Text = '中速';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_MY9

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end